classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetsimulationPanel            matlab.ui.container.Panel
        DefendersEditFieldLabel       matlab.ui.control.Label
        DefendersEditField            matlab.ui.control.NumericEditField
        AttackerdEditFieldLabel       matlab.ui.control.Label
        AttackerdEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        ReplaysimulationButton        matlab.ui.control.Button
        SetnumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SetnumberofiterationsSpinner  matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            map = binaryOccupancyMap(20,20);
            x = 9:12;
            y = 9:12;
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(map, xy, ones(16,1))
            figure
            numOfDefenders = app.DefendersEditField.Value;
            numOfAttackers = app.AttackerdEditField.Value;
            numOfRobots = numOfDefenders + numOfAttackers;
            x2 = rand(1,numOfRobots)*20;
            y2 = rand(1,numOfRobots)*20;
            i = 0;
            while i < 20
               clf;
               show(map);
               hold on;
               voronoi(x2,y2);
               x2 = x2 + (0.8)*(-1)^i;
               y2 = y2 + 0.08;
               pause(0.2);
               i = i + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetsimulationPanel = uipanel(app.UIFigure);
            app.SetsimulationPanel.Title = 'Set simulation';
            app.DefendersEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.DefendersEditFieldLabel.Text = 'Defenders';
            app.DefendersEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.DefendersEditField.Value = 2;
            app.AttackerdEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AttackerdEditFieldLabel.Text = 'Attackerd';
            app.AttackerdEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AttackerdEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.SetnumberofiterationsSpinnerLabel = uilabel(app.SetsimulationPanel);
            app.SetnumberofiterationsSpinnerLabel.Text = 'Set number of iterations';
            app.SetnumberofiterationsSpinner = uispinner(app.SetsimulationPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end